classdef App_Formula2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        general               matlab.ui.control.UIAxes
        UITable               matlab.ui.control.Table
        Switch                matlab.ui.control.Switch
        curva1                matlab.ui.control.UIAxes
        curva2                matlab.ui.control.UIAxes
        IniciarCarreraButton  matlab.ui.control.Button
        Switch_2              matlab.ui.control.Switch
        tEditFieldLabel       matlab.ui.control.Label
        deltax                matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarCarreraButton
        function IniciarCarreraButtonPushed(app, event)
            syms a0 a1 a2 a3 x
            set(app.UITable,'Data',[])
            value = app.Switch.Value;
            value2 = app.Switch_2.Value;
            hold(app.general,"on");
            hold(app.curva1,"on");
            hold(app.curva2,"on");
            
            delta =app.deltax.Value;
            
            %Establecer escalas curvas
            xlim(app.curva1, [750 880]);
            ylim(app.curva1,[-3040 -2990]);
            
            xlim(app.curva2, [2320 2440])
            ylim(app.curva2,[1270 1330]);
            
            %%Obtener funcion
            x1=100;     %Punto inicial
            y1=500;
            
            x2=2800;    %Punto Final
            y2=200;     
            
            eq1= a3*x1^3+a2*x1^2+a1*x1+a0==y1;
            eq2= a3*x2^3+a2*x2^2+a1*x2+a0==y2;
            
            %Editables
            x3=775;     %Punto 1
            y3=-3000;
            
            x4=2125;    %Punto 2
            y4=1000;
            
            eq3= a3*x3^3+a2*x3^2+a1*x3+a0==y3;
            eq4= a3*x4^3+a2*x4^2+a1*x4+a0==y4;
            
            sol=solve([eq1,eq2,eq3,eq4],[a0,a1,a2,a3]);%%Solucion lineal de la ecuacion
            
            v1=double(sol.a0); %Se asignan los valores de cada coeficiente
            v2=double(sol.a1);
            v3=double(sol.a2);
            v4=double(sol.a3);
            
            fprintf("y=%.9fx^3+%.9fx^2+%.9fx+%.9f\n",v4,v3,v2,v1); %Se imprime la funcion
            
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %%Longitud de curvatura
            fx=v4*x^3+v3*x^2+v2*x+v1;  %Funcion general 
            fxd=diff(fx);              %Derivada de la funcion
            fxl=sqrt(1+(fxd^2));       
            disp(simplify(diff(fx)));
            lg=int(fxl,x,[x1,x2]);     %Longitud de curva
            
            fprintf("Longitud de curva: %0.4f\n",lg);   %Se imprime la longitud
            
            fprintf("Longitud de curva: %0.4f\n",lg);   %Se imprime la longitud
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Obtener minimos del radio de curvatura
            
            mini = 797;
            min = 93.687470156344;
            mini2 = 2354;
            min2 = 99.967353;
            
            fprintf("El valor minimo del radio %i de curvatura es de: %.12f\n",mini, min);      %Primer punto minimo de la funcion
            fprintf("El valor minimo del radio %i de curvatura es de: %.12f\n",mini2, min2);    %Segundo punto minimo de la funcion
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %%Tangente y punto medio de la grada_1
            y=fx;   %Funcion general
            puntox= mini;   %Punto minimo 1 de radio de curvatura
            yprim=diff(y);  %Derivada de la funcion
            m = subs(yprim,x,puntox);   %Se obtiene la pendiente en el minimo en el punto
            puntoy=subs(y,x,puntox);    %Se encuentra el punto y
            fprintf("Pendiente 1 radio curvatura minimo: %.9f\n",m);    %Se imprime la pendiente 
            fprintf("Punto 1 radio curvatura minimo: (%.9f,%.9f)\n",puntox,puntoy); %Se imprime el punto 1
            y2f=m*x-m*puntox+puntoy;    %Se establece la funcion de la recta tangente
            y2t=y2f; %Se crea una nueva variable para la funcion tangente
            fprintf("La funcion en el punto 1 es: y=");
            disp(y2t); %Se imprime la funcion
            if(m>0) %Se evalua si la pendiente es positiva se establece una distancia sobre la tangente de -30
                f1 =-30;
            else    %Si es negativa la distancia es de 30 sobre la tangente
                f1 =30;
            end
            x2f= puntox+f1*sqrt(1/(1+m^2)); %Se obtiene la X sobre la tangente que de distancia sea 20
            y2f= puntoy+m*f1*sqrt(1/(1+m^2));   %Se obtiene la Y sobre la tangente que de distancia sea 20
            fprintf("Posicion del centro de la 1er grada: (%.9f,%.9f)\n",x2f,y2f);   %Se imprime el punto central frontal de las gradas
            
            %%Tangente y punto medio de la grada_2
            
            y_2=fx; %Funcion general
            puntox_2= mini2;    %Punto minimo 2 de radio de curvatura
            yprim_2=diff(y);    %Derivada de la funcion
            m_2 = subs(yprim_2,x,puntox_2);   %Se obtiene la pendiente en el minimo en el punto
            puntoy_2=subs(y_2,x,puntox_2);    %Se encuentra el punto Y
            fprintf("Pendiente 2 radio de curvatura minimo: %.9f\n",m_2);   %Se imprime la pendiente
            fprintf("Punto 2 radio de curvatura minimo: (%.9f,%.9f)\n",puntox_2,puntoy_2); %Se imprime el punto 2
            y2f_2=m_2*x-m_2*puntox_2+puntoy_2;  %Se establece la funcion de la recta tangente
            y2t_2 = y2f_2;  %Se crea una nueva variable para la funcion tangente
            fprintf("La funcion en el punto 2 es: y=");   
            disp(y2t_2);    %Se imrpime la funcion
            if(m_2>0)   %Se evalua si la pendiente es positiva se establece una distancia sobre la tangente de 30
                f1_2 =30;
            else        %Si es negativa la distancia es de -30 sobre la tangente
                f1_2 =-30;
            end
            x2f_2= puntox_2+f1_2*sqrt(1/(1+m_2^2)); %Se obtiene la X sobre la tangente que de distancia sea 20
            y2f_2= puntoy_2+m_2*f1_2*sqrt(1/(1+m_2^2)); %Se obtiene la Y sobre la tangente que de distancia sea 20
            fprintf("Posicion del centro de la 2da grada: (%.9f,%.9f)\n",x2f_2,y2f_2);   %Se imprime el punto central frontal de las gradas
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %obtener puntos de la 1er barrera
            xgrada1_i=x2f-40; %Esquina izquierda de la barrera
            ygrada1_i=y2f;  
            
            xgrada1_f=x2f+40; %Esquina derecha de la barrera
            ygrada1_f=y2f;
            
            fprintf("Coordenadas barrera1: (%.9f,%.9f),(%.9f,%.9f)\n",xgrada1_i,ygrada1_i,xgrada1_f,ygrada1_f);
            
            %obtener puntos de la 2da barrera
            xgrada2_i=x2f_2-40; %Esquina izquierda de la barrera
            ygrada2_i=y2f_2;
            
            xgrada2_f=x2f_2+40; %Esquina derecha de la barrera
            ygrada2_f=y2f_2;
            
            fprintf("Coordenadas barrera2: (%.9f,%.9f),(%.9f,%.9f)\n",xgrada2_i,ygrada2_i,xgrada2_f,ygrada2_f); %Se imprimen las coordenadas
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Puntos relevantes de la funcion
            
            %Puntos criticos
            fder = diff(fx);    %Se obtiene la derivada de la funcion
            fder = simplify(fder);  %Se simplifica la derivada de la funcion (a decimales)
            crit_p = solve(fder);   %Se aplica solucion lineal a la funcion, obteniendo los puntos minimos/maximos
            fprintf("Puntos Criticos: ");
            disp(crit_p);
            
            %Puntos de inflexion
            fder = diff(diff(fx));  %Se obtiene la segunda derivada
            inflec_pt = solve(fder,'MaxDegree',3);  %Se resuelve la segunda derivada donde cambia la concavidad de la funcion de 3 grado
            idx = imag(double(inflec_pt)) == 0;     %Se resuelve donde es 0 en la funcion
            inflec_pt = inflec_pt(idx);             %Se obtienen y almacenan las coordenadas del punto de inflexion
            fprintf("Puntos Inflexion: ");
            disp(inflec_pt);
            
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Plot graph
            
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Graficar funcion general
            
            %Graficar gradas
                hold(app.general,"off");
                hold(app.curva1,"off");
                hold(app.curva2,"off");
                %Punto inicial
                plot(app.general,x1,y1,'or');
                hold(app.general,"on");
                plot(app.curva1,x1,y1,'or');
                hold(app.curva1,"on");
                plot(app.curva2,x1,y1,'or');
                hold(app.curva2,"on");
                %Punto final
                plot(app.general,x2,y2,'ob');
                hold(app.general,"on");
                plot(app.curva1,x2,y2,'ob');
                hold(app.curva1,"on");
                plot(app.curva2,x2,y2,'ob');
                hold(app.curva2,"on");
                %Funcion general
                fplot(app.general,fx,[x1,x2],'color',[0 0 0]);
                hold(app.general,"on");
                fplot(app.curva1,fx,[x1,x2],'color',[0 0 0]);
                hold(app.curva1,"on");
                fplot(app.curva2,fx,[x1,x2],'color',[0 0 0]);
                hold(app.curva2,"on");
                
                %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
                %Puntos criticos e inflexion
            
                %Puntos criticos
                plot(app.general,double(crit_p), double(subs(fx,crit_p)),'rx'); %Se grafican todos los puntos criticos/max/min de la funcion con una x en color rojo
                %%text(app.general,double(crit_p), double(subs(fx,crit_p)),'Minimo/Maximo Local');    %Se agrega %texto sobre las coordenadas de los puntos
                hold(app.general,"on");
                plot(app.curva1,double(crit_p), double(subs(fx,crit_p)),'rx'); %Se grafican todos los puntos criticos/max/min de la funcion con una x en color rojo
                %%text(app.curva1,double(crit_p), double(subs(fx,crit_p)),'Minimo/Maximo Local');    %Se agrega %texto sobre las coordenadas de los puntos
                hold(app.curva1,"on");
                plot(app.curva2,double(crit_p), double(subs(fx,crit_p)),'rx'); %Se grafican todos los puntos criticos/max/min de la funcion con una x en color rojo
                %%text(app.curva2,double(crit_p), double(subs(fx,crit_p)),'Minimo/Maximo Local');    %Se agrega %texto sobre las coordenadas de los puntos
                hold(app.curva2,"on");
            
                %Punto inflexion
                plot(app.general,double(inflec_pt), double(subs(fx,inflec_pt)),'bx');   %Se grafican los puntos de inflexion con una x en color azul
                %%text(app.general,double(inflec_pt), double(subs(fx,inflec_pt)),'Punto inflexion')   %Se agrega %texto sobre las coordenadas de los puntos
                hold(app.general,"on");
                plot(app.curva1,double(inflec_pt), double(subs(fx,inflec_pt)),'bx');   %Se grafican los puntos de inflexion con una x en color azul
                %text(app.curva1,double(inflec_pt), double(subs(fx,inflec_pt)),'Punto inflexion')   %Se agrega %texto sobre las coordenadas de los puntos
                hold(app.curva1,"on");
                plot(app.curva2,double(inflec_pt), double(subs(fx,inflec_pt)),'bx');   %Se grafican los puntos de inflexion con una x en color azul
                %text(app.curva2,double(inflec_pt), double(subs(fx,inflec_pt)),'Punto inflexion')   %Se agrega %texto sobre las coordenadas de los puntos
                hold(app.curva2,"on");
            
                %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
                %Tangentes
            
                %Tangente punto 1 minimo del radio
                fplot(app.general,y2t,[x1 x2], '--','color',[.32 .133 .9]); %Se grafica la funcion tangente del punto 1 minimo del radio 
                hold(app.general,"on");
                fplot(app.curva1,y2t,[x1 x2], '--','color',[.32 .133 .9]); %Se grafica la funcion tangente del punto 1 minimo del radio 
                hold(app.curva1,"on");
                fplot(app.curva2,y2t,[x1 x2], '--','color',[.32 .133 .9]); %Se grafica la funcion tangente del punto 1 minimo del radio 
                hold(app.curva2,"on");
                
                %Tangente punto 2 minimo del radio
                fplot(app.general,y2t_2,[x1 x2], '--','color',[.32 .133 .9]);   %Se grafica la funcion tangente del punto 2 minimo del radio
                hold(app.general,"on");
                fplot(app.curva1,y2t_2,[x1 x2], '--','color',[.32 .133 .9]);   %Se grafica la funcion tangente del punto 2 minimo del radio
                hold(app.curva1,"on");
                fplot(app.curva2,y2t_2,[x1 x2], '--','color',[.32 .133 .9]);   %Se grafica la funcion tangente del punto 2 minimo del radio
                hold(app.curva2,"on");
            
                %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
                %Puntos con radio menor a 100
            
                %Punto 1 radio<=100
                plot(app.general,puntox, puntoy, 'or'); %Se grafica el punto 1 minimo del radio
                hold(app.general,"on");
                plot(app.curva1,puntox, puntoy, 'or'); %Se grafica el punto 1 minimo del radio
                hold(app.curva1,"on");
                plot(app.curva2,puntox, puntoy, 'or'); %Se grafica el punto 1 minimo del radio
                hold(app.curva2,"on");
            
                %Punto 2 radio<=100
                plot(app.general,puntox_2, puntoy_2, 'or'); %Se grafica el punto 1 minimo del radio
                hold(app.general,"on");
                plot(app.curva1,puntox_2, puntoy_2, 'or'); %Se grafica el punto 1 minimo del radio
                hold(app.curva1,"on");
                plot(app.curva2,puntox_2, puntoy_2, 'or'); %Se grafica el punto 1 minimo del radio
                hold(app.curva2,"on");
            
                %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
                %Punto Central Gradas 1
                plot(app.general,x2f, y2f, 'o', 'color',[.79 .5 .181]); %Se grafica el punto central de la primer grada/barrera
                hold(app.general,"on");
                plot(app.curva1,x2f, y2f, 'o', 'color',[.79 .5 .181]); %Se grafica el punto central de la primer grada/barrera
                hold(app.curva1,"on");
                plot(app.curva2,x2f, y2f, 'o', 'color',[.79 .5 .181]); %Se grafica el punto central de la primer grada/barrera
                hold(app.curva2,"on");
            
                %Punto Central Gradas 2
                plot(app.general,x2f_2, y2f_2, 'o','color',[.79 .5 .181]);  %Se grafica el punto central de la segunda grada/barrera
                hold(app.general,"on");
                plot(app.curva1,x2f_2, y2f_2, 'o','color',[.79 .5 .181]);  %Se grafica el punto central de la segunda grada/barrera
                hold(app.curva1,"on");
                plot(app.curva2,x2f_2, y2f_2, 'o','color',[.79 .5 .181]);  %Se grafica el punto central de la segunda grada/barrera
                hold(app.curva2,"on");
            
                %%Grada 1
                plot(app.general,xgrada1_i,ygrada1_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la primer grada/barrera
                hold(app.general,"on");
                plot(app.general,xgrada1_f,ygrada1_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la segunda grada/barrera
                plot(app.general,[xgrada1_i, xgrada1_f], [ygrada1_f, ygrada1_f],'color',[0 0 0]);   %Se grafica una linea horizontal entre el izquierdo 1 y derecho 1
                
                plot(app.curva1,xgrada1_i,ygrada1_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la primer grada/barrera
                hold(app.curva1,"on");
                plot(app.curva1,xgrada1_f,ygrada1_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la segunda grada/barrera
                plot(app.curva1,[xgrada1_i, xgrada1_f], [ygrada1_f, ygrada1_f],'color',[0 0 0]);   %Se grafica una linea horizontal entre el izquierdo 1 y derecho 1
                
                plot(app.curva2,xgrada1_i,ygrada1_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la primer grada/barrera
                hold(app.curva2,"on");
                plot(app.curva2,xgrada1_f,ygrada1_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la segunda grada/barrera
                plot(app.curva2,[xgrada1_i, xgrada1_f], [ygrada1_f, ygrada1_f],'color',[0 0 0]);   %Se grafica una linea horizontal entre el izquierdo 1 y derecho 1
                
                gx1_i=[xgrada1_i xgrada1_i];   
                gy1_i=[ygrada1_f ygrada1_f-10];
                plot(app.general,gx1_i,gy1_i,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto izquierdo 1 e izquierdo 2
                plot(app.curva1,gx1_i,gy1_i,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto izquierdo 1 e izquierdo 2
                plot(app.curva2,gx1_i,gy1_i,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto izquierdo 1 e izquierdo 2
            
                hold(app.general,"on");
                plot(app.general,xgrada1_i,ygrada1_f-10,'o','color',[0 0 0]);
                hold(app.general,"on");
                plot(app.general,xgrada1_f,ygrada1_f-10,'o','color',[0 0 0]);
                plot(app.general,[xgrada1_i, xgrada1_f], [ygrada1_f-10, ygrada1_f-10],'color',[0 0 0]); %Se grafica una linea horizontal entre el izquierdo 2 y derecho 2
                
                hold(app.curva1,"on");
                plot(app.curva1,xgrada1_i,ygrada1_f-10,'o','color',[0 0 0]);
                hold(app.curva1,"on");
                plot(app.curva1,xgrada1_f,ygrada1_f-10,'o','color',[0 0 0]);
                plot(app.curva1,[xgrada1_i, xgrada1_f], [ygrada1_f-10, ygrada1_f-10],'color',[0 0 0]); %Se grafica una linea horizontal entre el izquierdo 2 y derecho 2 
                
                hold(app.curva2,"on");
                plot(app.curva2,xgrada1_i,ygrada1_f-10,'o','color',[0 0 0]);
                hold(app.curva2,"on");
                plot(app.curva2,xgrada1_f,ygrada1_f-10,'o','color',[0 0 0]);
                plot(app.curva2,[xgrada1_i, xgrada1_f], [ygrada1_f-10, ygrada1_f-10],'color',[0 0 0]); %Se grafica una linea horizontal entre el izquierdo 2 y derecho 2 
                
                gx1_f=[xgrada1_f xgrada1_f];
                gy1_f=[ygrada1_f ygrada1_f-10];
                plot(app.general,gx1_f,gy1_f,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto derecho 1 y derecho 2
                plot(app.curva1,gx1_f,gy1_f,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto derecho 1 y derecho 2
                plot(app.curva2,gx1_f,gy1_f,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto derecho 1 y derecho 2
            
                hold(app.general,"on");
                hold(app.curva1,"on");
                hold(app.curva2,"on");
            
                %Gradas 2
                plot(app.general,xgrada2_i,ygrada2_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la primer grada/barrera
                hold(app.general,"on");
                plot(app.curva1,xgrada2_i,ygrada2_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la primer grada/barrera
                hold(app.curva1,"on");
                plot(app.curva2,xgrada2_i,ygrada2_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la primer grada/barrera
                hold(app.curva2,"on");
                plot(app.general,xgrada2_f,ygrada2_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la segunda grada/barrera
                plot(app.general,[xgrada2_i, xgrada2_f], [ygrada2_f, ygrada2_f],'color',[0 0 0]) %Se grafica una linea horizontal entre el izquierdo 1 y derecho 1 
                plot(app.curva1,xgrada2_f,ygrada2_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la segunda grada/barrera
                plot(app.curva1,[xgrada2_i, xgrada2_f], [ygrada2_f, ygrada2_f],'color',[0 0 0]) %Se grafica una linea horizontal entre el izquierdo 1 y derecho 1 
                plot(app.curva2,xgrada2_f,ygrada2_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la segunda grada/barrera
                plot(app.curva2,[xgrada2_i, xgrada2_f], [ygrada2_f, ygrada2_f],'color',[0 0 0]) %Se grafica una linea horizontal entre el izquierdo 1 y derecho 1 
                
                gx2_i=[xgrada2_i xgrada2_i];
                gy2_i=[ygrada2_f ygrada2_f+10];
                plot(app.general,gx2_i,gy2_i,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto izquierdo 1 e izquierdo 2
                plot(app.curva1,gx2_i,gy2_i,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto izquierdo 1 e izquierdo 2
                plot(app.curva2,gx2_i,gy2_i,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto izquierdo 1 e izquierdo 2
            
                hold(app.general,"on");
                plot(app.general,xgrada2_i,ygrada2_f+10,'o','color',[0 0 0]);
                hold(app.general,"on");
                plot(app.general,xgrada2_f,ygrada2_f+10,'o','color',[0 0 0]);
                plot(app.general,[xgrada2_i, xgrada2_f], [ygrada2_f+10, ygrada2_f+10],'color',[0 0 0]) %Se grafica una linea horizontal entre el derecho 1 e izquierdo 2 
                hold(app.curva1,"on");
                plot(app.curva1,xgrada2_i,ygrada2_f+10,'o','color',[0 0 0]);
                hold(app.curva1,"on");
                plot(app.curva1,xgrada2_f,ygrada2_f+10,'o','color',[0 0 0]);
                plot(app.curva1,[xgrada2_i, xgrada2_f], [ygrada2_f+10, ygrada2_f+10],'color',[0 0 0]) %Se grafica una linea horizontal entre el derecho 1 e izquierdo 2 
                hold(app.curva2,"on");
                plot(app.curva2,xgrada2_i,ygrada2_f+10,'o','color',[0 0 0]);
                hold(app.curva2,"on");
                plot(app.curva2,xgrada2_f,ygrada2_f+10,'o','color',[0 0 0]);
                plot(app.curva2,[xgrada2_i, xgrada2_f], [ygrada2_f+10, ygrada2_f+10],'color',[0 0 0]) %Se grafica una linea horizontal entre el derecho 1 e izquierdo 2 
                
                gx2_f=[xgrada2_f xgrada2_f];
                gy2_f=[ygrada2_f ygrada2_f+10];
                plot(app.general,gx2_f,gy2_f,'color',[0 0 0]); %Se grafica una linea vertical entre el punto derecho 1 y derecho 2
                plot(app.curva1,gx2_f,gy2_f,'color',[0 0 0]); %Se grafica una linea vertical entre el punto derecho 1 y derecho 2
                plot(app.curva2,gx2_f,gy2_f,'color',[0 0 0]); %Se grafica una linea vertical entre el punto derecho 1 y derecho 2
            
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Graficar particula
            x = 100:.01:2800;
            y = -0.000002249*x.^3+0.010770716*x.^2-13.061982421*x+1700.740063337;
            px = 100;
            py = 500;
            if(value =="Sin Derrape")
                for i=px:delta:2800
                    py = -0.000002249*i.^3+0.010770716*i.^2-13.061982421*i+1700.740063337;
                    ph = plot(app.general,i, py,'ob');
                    ph1 = plot(app.curva1,i, py,'ob');
                    ph2 = plot(app.curva2,i, py,'ob');
                    pause(0.05);
                end
            else
                if(value2 == "Curva 1")
                    for i=px:delta:816
                        if i < 797
                            py = -0.000002249*i.^3+0.010770716*i.^2-13.061982421*i+1700.740063337;
                        else
                            py = m*i-m*puntox+puntoy;
                        end
                        ph = plot(app.general,i, py,'ob');
                        ph1 = plot(app.curva1,i, py,'ob');
                        ph2 = plot(app.curva2,i, py,'ob');
                        pause(0.05);
                    end
                end
                if(value2 == "Curva 2")
                    px = 100;
                    for i=px:delta:2373
                        if i < 2354
                            py = -0.000002249*i.^3+0.010770716*i.^2-13.061982421*i+1700.740063337;
                        else
                            py = m_2*i-m_2*puntox_2+puntoy_2;
                        end
                        ph = plot(app.general,i, py,'ob');
                        ph1 = plot(app.curva1,i, py,'ob');
                        ph2 = plot(app.curva2,i, py,'ob');
                        pause(0.05);
                    end
                end
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value2 = app.Switch_2.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.general = uiaxes(app.UIFigure);
            title(app.general, 'Vista General')
            xlabel(app.general, 'X')
            ylabel(app.general, 'Y')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'; 'Velocidad'; 'Aceleracion'};
            app.UITable.RowName = {};
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Sin Derrape', 'Derrape'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Sin Derrape';
            app.curva1 = uiaxes(app.UIFigure);
            title(app.curva1, 'Curva 1')
            xlabel(app.curva1, 'X')
            ylabel(app.curva1, 'Y')
            app.curva2 = uiaxes(app.UIFigure);
            title(app.curva2, 'Curva 2')
            xlabel(app.curva2, 'X')
            ylabel(app.curva2, 'Y')
            app.IniciarCarreraButton = uibutton(app.UIFigure, 'push');
            app.IniciarCarreraButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarCarreraButtonPushed, true);
            app.IniciarCarreraButton.Text = 'Iniciar Carrera';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Curva 1', 'Curva 2'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Curva 1';
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Text = 'Δt';
            app.deltax = uieditfield(app.UIFigure, 'numeric');
            app.deltax.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Formula2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end