classdef entregable_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LongitudcargapositivaEditFieldLabel  matlab.ui.control.Label
        LongitudcargapositivaEditField  matlab.ui.control.NumericEditField
        LongitudcarganegativaEditFieldLabel  matlab.ui.control.Label
        LongitudcarganegativaEditField  matlab.ui.control.NumericEditField
        DistanciaentrecargapositivaynegativaEditFieldLabel  matlab.ui.control.Label
        DistanciaentrecargapositivaynegativaEditField  matlab.ui.control.NumericEditField
        EjedecargasdefuenteButtonGroup  matlab.ui.container.ButtonGroup
        EjexButton                      matlab.ui.control.RadioButton
        EjeyButton                      matlab.ui.control.RadioButton
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        GraficarButton                  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    methods (Access = public)
        
        function [xPPos, yPPos, xNPos, yNPos, nP, nN, grafica] = posicionCargasFuente(~, E, d, lCP, lCN, grafica)
            n = 0.1;
            nP = lCP/n;
            nN = lCN/n;
            xPPos = zeros(1,nP);
            yPPos = zeros(1,nP);
            xNPos = zeros(1,nN);
            yNPos = zeros(1,nN);
            if E == 1 % caso de eje - x
                for i = 0:nP-1 % calcular posiciones de cargas positivas eje - y
                    xPPos(1,i+1) = (n/2) + ((nP-2)/2)*n - (i*n);
                end
                for i = 0:nN-1 % calcular posiciones de cargas positivas eje - y
                    xNPos(1,i+1) = (n/2) + ((nN-2)/2)*n - (i*n);
                end
                for i = 1:nP % calcular posiciones de cargas eje - y
                    yPPos(1,i) = d/2;
                end
                for i = 1:nN % calcular posiciones de cargas eje - y
                    yNPos(1,i) = -d/2;
                end
                if lCP > lCN % carga positiva mayor longitud
                    xlim(grafica, [(xPPos(1,nP)-1) (xPPos(1,1)+1)]) % limites en eje x
                else
                    xlim(grafica, [(xNPos(1,nN)-1) (xNPos(1,1)+1)]) % limites en eje x
                end
                ylim(grafica, [(yNPos(1,1)-1) (xNPos(1,1)+1)]) % limites en eje x 
            else % caso de eje - y
                for i = 0:nP-1 % calcular posiciones de cargas positivas eje - y
                    yPPos(1,i+1) = -(n/2) - ((nP-2)/2)*n + (i*n);
                end
                for i = 0:nN-1 % calcular posiciones de cargas positivas eje - y
                    yNPos(1,i+1) = -(n/2) - ((nN-2)/2)*n + (i*n);
                end
                for i = 1:nP % calcular posiciones de cargas eje - y
                    xPPos(1,i) = -d/2;
                end
                for i = 1:nN % calcular posiciones de cargas eje - y
                    xNPos(1,i) = d/2;
                end
                if lCP > lCN % carga positiva mayor longitud
                    ylim(grafica, [(yPPos(1,1)-1) (yPPos(1,nP)+1)]) % limites en eje x 
                else
                    ylim(grafica, [(yNPos(1,1)-1) (yNPos(1,nN)+1)]) % limites en eje x 
                end
                xlim(grafica, [(xPPos(1,nP)-1) (xNPos(1,1)+1)]) % limites en eje x
            end
        end
        
        function plotCargasFuente(~, xPPos, yPPos, xNPos, yNPos, nP, nN, t, grafica)
            hold(grafica, "on")
            for i = 1:nP
                rectangle(grafica, 'Position',[(xPPos(1,i)-(t/2)) (yPPos(1,i)-(t/2)) t t], 'Curvature',[1,1],'FaceColor','r',"EdgeColor","r");
                if i == nP
                    text(grafica, (xPPos(1,i)-0.05),(yPPos(1,i)+0.03),'+','Color','white','FontSize',20);
                end
            end
            for i = 1:nN
                rectangle(grafica, 'Position',[(xNPos(1,i)-(t/2)) (yNPos(1,i)-(t/2)) t t], 'Curvature',[1,1],'FaceColor','b',"EdgeColor","b");
                if i == nN
                    text(grafica, (xNPos(1,i)-0.03),(yNPos(1,i)+0.03),'-','Color','white','FontSize',30);
                end
            end
        end
        function [Et, Ex, Ey, tx, ty] = getVectores(~, xPPos, yPPos, xNPos, yNPos, lCP, lCN, n)
            pts = 300;
            x =linspace(xPPos(1,lCP/n)-10, xNPos(1,1)+10, pts);
            y =linspace(yNPos(1,1)-10, yPPos(1,lCP/n)+10, pts);
            [tx,ty] = meshgrid(x,y); % Definir matriz de vectores
            
            k =  1/(4*pi*8.54e-12); % constante k
            q =  1.6e-19; % Valor de cargas fuente
            Ex = zeros(pts);
            Ey = zeros(pts);
            Et = zeros(pts);
            
            for contx = 1:pts
                for conty = 1:pts
                    EpxT = 0;
                    EpyT = 0;
                    EnxT = 0;
                    EnyT = 0;
                    for i = 1:lCP/n
                        r = sqrt((tx(contx,conty) - xPPos(1,i))^2 + (ty(contx,conty) - yPPos(1,i))^2);
                        Ec = k*q/r^2;
                        EpxT = EpxT + Ec*(tx(contx,conty) - xPPos(1,i))/r;
                        EpyT = EpyT + Ec*(ty(contx,conty) - yPPos(1,i))/r;
                    end
                    for i = 1:lCN/n
                        r = sqrt((tx(contx,conty) - xNPos(1,i))^2 + (ty(contx,conty) - yNPos(1,i))^2);
                        Ec = -k*q/r^2;
                        EnxT = EnxT + Ec*(tx(contx,conty) - xNPos(1,i))/r;
                        EnyT = EnyT + Ec*(ty(contx,conty) - yNPos(1,i))/r;
                    end
                    Ex(contx, conty) = EpxT + EnxT;
                    Ey(contx, conty) = EpyT + EnyT;
                    Et(contx,conty) = sqrt(Ex(contx, conty)^2 + Ey(contx, conty)^2);
                end
            end
        end
        
        function plotVectores(~, Et, Ex, Ey, tx, ty, grafica)
            hold(grafica, "on")
            quiver(grafica, tx, ty, Ex./Et, Ey./Et, 'AutoScaleFactor',0.5)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            % <---------Reset axis---------->
            cla(app.UIAxes,'reset');
            
            % <---------Definicion de variables---------->
            lCP = app.LongitudcargapositivaEditField.Value; % Numero de cargas positivas
            lCN = app.LongitudcarganegativaEditField.Value; % Numero de cargas negativas
            E = app.EjexButton.Value; % Eje seleccionado (0 = eje-x o 1 = eje-y)
            d = app.DistanciaentrecargapositivaynegativaEditField.Value; % Distancia entre cargas positivas y negativas
            
            % <---------Obtener posiciones de cargas fuente---------->
            [xPPos, yPPos, xNPos, yNPos, nP, nN, grafica] = posicionCargasFuente(app, E, d, lCP, lCN, app.UIAxes);
            
            % <---------Graficar de cargas fuente---------->
            plotCargasFuente(app, xPPos, yPPos, xNPos, yNPos, nP, nN, 0.5, grafica);
            
            % <---------Obtener campo electrico vectorial---------->
            [Et, Ex, Ey, tx, ty] = getVectores(app, xPPos, yPPos, xNPos, yNPos, lCP, lCN, 0.1);
            
            % <---------Graficar vectores resultantes---------->
            plotVectores(app, Et, Ex, Ey, tx, ty, grafica)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {492, 492};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {383, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LongitudcargapositivaEditFieldLabel = uilabel(app.LeftPanel);
            app.LongitudcargapositivaEditFieldLabel.Text = 'Longitud carga positiva';
            app.LongitudcargapositivaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LongitudcarganegativaEditFieldLabel = uilabel(app.LeftPanel);
            app.LongitudcarganegativaEditFieldLabel.Text = 'Longitud carga negativa';
            app.LongitudcarganegativaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DistanciaentrecargapositivaynegativaEditFieldLabel = uilabel(app.LeftPanel);
            app.DistanciaentrecargapositivaynegativaEditFieldLabel.Text = 'Distancia entre carga positiva y negativa';
            app.DistanciaentrecargapositivaynegativaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EjedecargasdefuenteButtonGroup = uibuttongroup(app.LeftPanel);
            app.EjedecargasdefuenteButtonGroup.Title = 'Eje de cargas de fuente';
            app.EjexButton = uiradiobutton(app.EjedecargasdefuenteButtonGroup);
            app.EjexButton.Text = 'Eje x';
            app.EjexButton.Value = true;
            app.EjeyButton = uiradiobutton(app.EjedecargasdefuenteButtonGroup);
            app.EjeyButton.Text = 'Eje y';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GraficarButton = uibutton(app.RightPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = entregable_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end