classdef entregable1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CampomagnticoUIFigure        matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        OrientaciondelaespiraSwitchLabel  matlab.ui.control.Label
        OrientaciondelaespiraSwitch  matlab.ui.control.Switch
        FuncindemodelajeSwitchLabel  matlab.ui.control.Label
        FuncindemodelajeSwitch       matlab.ui.control.Switch
        CorrienteAEditFieldLabel     matlab.ui.control.Label
        CorrienteAEditField          matlab.ui.control.NumericEditField
        RadiomEditFieldLabel         matlab.ui.control.Label
        RadiomEditField              matlab.ui.control.NumericEditField
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        SimularButton                matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function plotSpira(app)
            radius = abs(app.RadiomEditField.Value);
            if(app.OrientaciondelaespiraSwitch.Value == "Horizontal")
                line(app.UIAxes,[-radius/2,radius/2],[0,0],"Linewidth",4,"Color","r");
            else
                line(app.UIAxes,[0,0],[-radius/2,radius/2],"Linewidth",4,"Color","r");
            end
            
        end
        
        function [X, Y, Bx, By, Bt] = getMagneticField(app)
            % Declarar constantes
            mu = 1.2566370614e-6;
            radius = abs(app.RadiomEditField.Value);
            I = app.CorrienteAEditField.Value;
            
            % Declarar matrices iniciales
            min = -radius/2 - 100;
            max = radius/2 + 100;
            n = 50;
            [X, Y] = meshgrid(linspace(min, max, n), linspace(min, max, n));
            Bx = zeros(n);
            By = zeros(n);
            deltax = Y;
            deltay = X;
            
            % Calculo de nuevas matrices
            R = sqrt(deltax.^2 + deltay.^2);
            if(app.OrientaciondelaespiraSwitch.Value == "horizontal")
                Bx = Bx + ((mu * I * radius^2 ./ (4.*R.^5)) .* ((3.*deltay.*deltax)));
                By = By + ((mu * I * radius^2 ./ (4.*R.^3)) .* ((3.*deltax.^2) ./(R.^2) -1)) ;
            else
                Bx = -(Bx + ((mu * I * radius^2 ./ (4.*R.^3)) .* ((3.*deltay.^2) ./(R.^2)-1)));
                By = -(By + ((mu * I * radius^2 ./ (4.*R.^5)) .* ((3.*deltax.*deltay))));
            end
            Bt = sqrt(Bx.^2 + By.^2);
            axis(app.UIAxes,[min max min max]);
        end
        
        function plotMagneticField(app, X, Y, Bx, By, Bt)
            if(app.FuncindemodelajeSwitch.Value == "Quiver")
                quiver(app.UIAxes, X, Y, -Bx./Bt, -By./Bt, 'color', 'b');
            else
                streamslice(app.UIAxes, X, Y, -Bx./Bt, -By./Bt, 'color', 'b');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            clc;
            [X, Y, Bx, By, Bt] = getMagneticField(app);
            plotMagneticField(app, X, Y, Bx, By, Bt);
            hold(app.UIAxes, 'on');
            plotSpira(app);
            title(app.UIAxes,'Campo magnético de una espira');
            xlabel(app.UIAxes,'x(m)');
            ylabel(app.UIAxes,'y(m)');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CampomagnticoUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {540, 540};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {288, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CampomagnticoUIFigure = uifigure('Visible', 'off');
            app.CampomagnticoUIFigure.Name = 'Campo magnético';
            app.CampomagnticoUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.CampomagnticoUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OrientaciondelaespiraSwitchLabel = uilabel(app.LeftPanel);
            app.OrientaciondelaespiraSwitchLabel.Text = 'Orientacion de la espira';
            app.OrientaciondelaespiraSwitch = uiswitch(app.LeftPanel, 'slider');
            app.OrientaciondelaespiraSwitch.Items = {'Horizontal', 'Vertical'};
            app.OrientaciondelaespiraSwitch.Value = 'Horizontal';
            app.FuncindemodelajeSwitchLabel = uilabel(app.LeftPanel);
            app.FuncindemodelajeSwitchLabel.Text = 'Función de modelaje';
            app.FuncindemodelajeSwitch = uiswitch(app.LeftPanel, 'slider');
            app.FuncindemodelajeSwitch.Items = {'Streamline', 'Quiver'};
            app.FuncindemodelajeSwitch.Value = 'Streamline';
            app.CorrienteAEditFieldLabel = uilabel(app.LeftPanel);
            app.CorrienteAEditFieldLabel.Text = 'Corriente (A)';
            app.CorrienteAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CorrienteAEditField.Value = 1;
            app.RadiomEditFieldLabel = uilabel(app.LeftPanel);
            app.RadiomEditFieldLabel.Text = 'Radio (m)';
            app.RadiomEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RadiomEditField.Value = 20;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SimularButton = uibutton(app.RightPanel, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            % Show the figure after all components are created
            app.CampomagnticoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = entregable1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CampomagnticoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CampomagnticoUIFigure)
        end
    end
end