classdef entregable3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TipodematerialButtonGroup     matlab.ui.container.ButtonGroup
        HierroButton                  matlab.ui.control.RadioButton
        NiquelButton                  matlab.ui.control.RadioButton
        AceroButton                   matlab.ui.control.RadioButton
        CobaltoButton                 matlab.ui.control.RadioButton
        AlturamEditFieldLabel         matlab.ui.control.Label
        AlturamEditField              matlab.ui.control.NumericEditField
        MasakgEditFieldLabel          matlab.ui.control.Label
        MasakgEditField               matlab.ui.control.NumericEditField
        TiempoinicialsEditFieldLabel  matlab.ui.control.Label
        TiempoinicialsEditField       matlab.ui.control.NumericEditField
        TiempofinalsEditFieldLabel    matlab.ui.control.Label
        TiempofinalsEditField         matlab.ui.control.NumericEditField
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        SimularButton                 matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function plotZ(app, t, z, a)
            plot(app.UIAxes, t, z(:,1), 'linewidth', 1.5, 'color', 'b');
            
            plot(app.UIAxes_2, t, z(:,2), 'linewidth', 1.5, 'color', 'r');
            
            plot(app.UIAxes3, t, a, 'linewidth', 1.5, 'color', 'g');
        end
       
        function [t,z] = calculateValues(app)
            % define time intervals
            ti = abs(app.TiempoinicialsEditField.Value); % initial time
            tf = abs(app.TiempofinalsEditField.Value); % final time
            t = [ti,tf]; % time
            zvalues = [app.AlturamEditField.Value,0]; % initial values of height and velocity
            [t,z] = ode45(@func, t, zvalues); % call ode45 wich returns the values of height
            
            function result = func(t,z)
                mass = abs(app.MasakgEditField.Value); % mass of dipolen (kg)
                g = 9.81; % gravity
                % calculate permeability and resistivity
		if(app.TipodematerialButtonGroup.SelectedObject.Text == "Hierro")
                    miu = 5000; 
                    R = 1.0*10^-7; 
                elseif(app.TipodematerialButtonGroup.SelectedObject.Text == "Niquel")
                    miu = 600; 
                    R = 6.99*10^-8;
                elseif(app.TipodematerialButtonGroup.SelectedObject.Text == "Acero")
                    miu = 100;
                    R = 6.9*10^-7; 
                else % caso de cobalto
                    miu = 250;
                    R = 6.24*10^-7; 
                end                      
                mu = 4*pi*(10^-7); % permeability in vacuum
                a = app.AlturamEditField.Value * 0.05; % radius
               
                k = (9*(mu * miu)^2 * (a^4))/(4*R*mass); % constant
                item1 = z(2); % item that will have the values of height
                item2 = -((k.*(z(1).^2).*z(2))) ./(((z(1).^2 + a^2).^5)) - g; % item that will have the values of velocity
               
                result = [item1; item2]; %create matrix with values of height and velocity
            end
        end
        
        function a = getAcceleration(app, z)
            mass = abs(app.MasakgEditField.Value); 
            g = 9.81;
            if(app.TipodematerialButtonGroup.SelectedObject.Text == "Hierro")
                miu = 5000; 
                R = 1.0*10^-7; 
            elseif(app.TipodematerialButtonGroup.SelectedObject.Text == "Niquel")
                miu = 600; 
                R = 6.99*10^-8;
            elseif(app.TipodematerialButtonGroup.SelectedObject.Text == "Acero")
                miu = 100;
                R = 6.9*10^-7; 
            else 
                miu = 250;
                R = 6.24*10^-7; 
            end   
            mu = 4*pi*(10^-7); % permeability in vacuum
            r = app.AlturamEditField.Value * 0.05; % radius
            k = (9*(mu * miu)^2 * (r^4))/(4*R*mass); % constant
            a = -((k.*(z(:,1).^2).*z(:,2))) ./(((z(:,1).^2 + r^2).^5)) - g;
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes_2,'reset');
            cla(app.UIAxes3,'reset');
            clc;
            [t,z] = calculateValues(app);
            a = getAcceleration(app, z);
            plotZ(app, t, z, a);
            ylabel(app.UIAxes,"posicion del dipolo (m)")
            xlabel(app.UIAxes,"tiempo transcurrido (s)")
            title(app.UIAxes,"altura del dipolo con respecto al tiempo")
            
            ylabel(app.UIAxes_2,"velocidad (m/s)")
            xlabel(app.UIAxes_2,"tiempo transcurrido (s)")
            title(app.UIAxes_2,"velocidad del dipolo con respecto al tiempo")
            
            ylabel(app.UIAxes3,"acelaration (m/s^2)")
            xlabel(app.UIAxes3,"tiempo transcurrido (s)")
            title(app.UIAxes3,"aceleracion del dipolo con respecto al tiempo")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {782, 782};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {346, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TipodematerialButtonGroup = uibuttongroup(app.LeftPanel);
            app.TipodematerialButtonGroup.Title = 'Tipo de material';
            app.HierroButton = uiradiobutton(app.TipodematerialButtonGroup);
            app.HierroButton.Text = 'Hierro';
            app.HierroButton.Value = true;
            app.NiquelButton = uiradiobutton(app.TipodematerialButtonGroup);
            app.NiquelButton.Text = 'Niquel';
            app.AceroButton = uiradiobutton(app.TipodematerialButtonGroup);
            app.AceroButton.Text = 'Acero';
            app.CobaltoButton = uiradiobutton(app.TipodematerialButtonGroup);
            app.CobaltoButton.Text = 'Cobalto';
            app.AlturamEditFieldLabel = uilabel(app.LeftPanel);
            app.AlturamEditFieldLabel.Text = 'Altura (m)';
            app.AlturamEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AlturamEditField.Limits = [0 Inf];
            app.AlturamEditField.Value = 1;
            app.MasakgEditFieldLabel = uilabel(app.LeftPanel);
            app.MasakgEditFieldLabel.Text = 'Masa (kg)';
            app.MasakgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MasakgEditField.Limits = [0 Inf];
            app.MasakgEditField.Value = 0.032;
            app.TiempoinicialsEditFieldLabel = uilabel(app.LeftPanel);
            app.TiempoinicialsEditFieldLabel.Text = 'Tiempo inicial (s)';
            app.TiempoinicialsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TiempoinicialsEditField.Limits = [0 30];
            app.TiempofinalsEditFieldLabel = uilabel(app.LeftPanel);
            app.TiempofinalsEditFieldLabel.Text = 'Tiempo final (s)';
            app.TiempofinalsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TiempofinalsEditField.Limits = [1 60];
            app.TiempofinalsEditField.Value = 10;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SimularButton = uibutton(app.RightPanel, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = entregable3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end